classdef CustomPolarPlot < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        PlotAndControls   matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        TimeQualityLamp   matlab.ui.control.Lamp
        TimeQualityLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        PolarAxes % Property used to manage polar plot
    end
    
    
    methods (Access = public)
        
        function UpdatePolarPlot(comp,Value,Angle)
            polarplot(comp.PolarAxes,[0 deg2rad(Angle)],[0 Value]);
        end
        
        function UpdateTimeQuality(comp,IsTimeOk)
            if IsTimeOk==true
                comp.TimeQualityLamp.Enable="off";
            else
                 comp.TimeQualityLamp.Enable="on";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.PolarAxes = polaraxes(comp.PlotAndControls);
            comp.PolarAxes.Layout.Row = 1;
            comp.PolarAxes.Layout.Column = 1;
            polarplot(comp.PolarAxes,[0 0],[0 10]);
            comp.TimeQualityLamp.Enable="on";
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.PlotAndControls = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.PlotAndControls);
            comp.TimeQualityLabel = uilabel(comp.GridLayout2);
            comp.TimeQualityLabel.Text = {'Time'; ' Quality'};
            comp.TimeQualityLamp = uilamp(comp.GridLayout2);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end