classdef ProjektSynchrofazory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AkcjeMenu           matlab.ui.container.Menu
        DodajurzdzenieMenu  matlab.ui.container.Menu
        UsuurzdzenieMenu    matlab.ui.container.Menu
        MapaMenu            matlab.ui.container.Menu
        UstawieniaMenu      matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        WykresykoloweTab    matlab.ui.container.Tab
        GridForPlots        matlab.ui.container.GridLayout
        WidokszczegowyTab   matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout7         matlab.ui.container.GridLayout
        ANALOGLabel         matlab.ui.control.Label
        ANALOG_BUTTON       matlab.ui.control.Button
        GridLayout6         matlab.ui.container.GridLayout
        PHASELabel          matlab.ui.control.Label
        PHASE_BUTTON        matlab.ui.control.Button
        GridLayout5         matlab.ui.container.GridLayout
        MAGNITUDELabel      matlab.ui.control.Label
        MAGNITUDE_BUTTON    matlab.ui.control.Button
        GridLayout4         matlab.ui.container.GridLayout
        DFREQ_BUTTON        matlab.ui.control.Button
        DFREQLabel          matlab.ui.control.Label
        GridLayout3         matlab.ui.container.GridLayout
        FREQ_BUTTON         matlab.ui.control.Button
        FREQLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        DataProvider;
        RefreshRate=15;
        Timer % Used to refresh plots every x seconds
        OptionsDialog UstawieniaDialog% Okno dialogowe dla zmiany ustawień
        PlotOptionsDialog PolarPlotOptions
        AddDialog DodajUrzadzenieDialog
        DeleteDialog UsunUrzadzenieDialog
        DeviceDetailsDialog DeviceDetailsDialog
        MapDialog MapDialog
        FREQ_figure 
        DFREQ_figure 
        MAGNITUDE_figure 
        PHASE_figure 
        ANALOG_figure
    end
   
    methods (Access = public)
          
        function TimerCallback(app, ~, ~)  

             if isvalid(app.DeviceDetailsDialog)
                 app.DeviceDetailsDialog.UpdateValues(app.DataProvider.ListPMU);
             end
            
             if ishandle(app.FREQ_figure) 
                 if isvalid(app.FREQ_figure)
                     if ~isempty(app.DataProvider)
                         if ~isempty(app.DataProvider.PMUdetails.FREQ) && ~isempty(app.DataProvider.PMUdetails.SOC)
                             set(0,'CurrentFigure',app.FREQ_figure);
                             x=app.DataProvider.PMUdetails.SOC;
                             y=app.DataProvider.PMUdetails.FREQ(1,:);
                             hold on;
                             subplot(1,1,1);
                             plot(x,y',"Color",[0 0 0],"LineWidth",1);
                                title("FREQ");
                             hold off;
                        end
                     end
                 end
             end

             if ishandle(app.DFREQ_figure) 
                 if isvalid(app.DFREQ_figure)
                     if ~isempty(app.DataProvider)
                         if ~isempty(app.DataProvider.PMUdetails.DFREQ) && ~isempty(app.DataProvider.PMUdetails.SOC)
                             set(0,'CurrentFigure',app.DFREQ_figure);
                             x=app.DataProvider.PMUdetails.SOC;
                             y=app.DataProvider.PMUdetails.DFREQ(1,:);
                             hold on;
                             subplot(1,1,1);
                             plot(x,y',"Color",[0 0 0],"LineWidth",1);
                                title("DFREQ");
                             hold off;
                        end
                     end
                 end
             end

             if ishandle(app.MAGNITUDE_figure) 
                 if isvalid(app.MAGNITUDE_figure)
                     if ~isempty(app.DataProvider)
                         if ~isempty(app.DataProvider.PMUdetails.PHASOR0) && ~isempty(app.DataProvider.PMUdetails.SOC)
                             set(0,'CurrentFigure',app.MAGNITUDE_figure);
                             [j,~] = size(app.DataProvider.PMUdetails.PHASOR0);
                             l=1;
                             x=app.DataProvider.PMUdetails.SOC;
                             hold on;
                             for m=1:j
                                subplot(j,1,l);
                                y=app.DataProvider.PMUdetails.PHASOR0(m,:);
                                plot(x,y,"Color",[0 0 0],"LineWidth",1);
                                l=l+1;
                                handle=app.DataProvider.ListPMU(app.DataProvider.DeviceID);
                                handle=handle.data.PHASORS1(app.DataProvider.DeviceSTN);
                                k=keys(handle);
                                key=char(cell2mat(k(m)));
                                title("MAGNITUDE-"+string(app.DataProvider.DeviceSTN)+"-"+string(key));
                             end
                             hold off;
                         end
                     end
                 end
             end

             if ishandle(app.PHASE_figure) 
                 if isvalid(app.PHASE_figure)
                     if ~isempty(app.DataProvider)
                         if ~isempty(app.DataProvider.PMUdetails.PHASOR1) && ~isempty(app.DataProvider.PMUdetails.SOC)
                             set(0,'CurrentFigure',app.PHASE_figure);
                             [j,~] = size(app.DataProvider.PMUdetails.PHASOR1);
                             l=1;
                             x=app.DataProvider.PMUdetails.SOC;
                             hold on;
                             for m=1:j
                                subplot(j,1,l);
                                y=app.DataProvider.PMUdetails.PHASOR1(m,:);
                                plot(x,y,"Color",[0 0 0],"LineWidth",1);
                                l=l+1;
                                handle=app.DataProvider.ListPMU(app.DataProvider.DeviceID);
                                handle=handle.data.PHASORS1(app.DataProvider.DeviceSTN);
                                k=keys(handle);
                                key=char(cell2mat(k(m)));
                                title("MAGNITUDE-"+string(app.DataProvider.DeviceSTN)+"-"+string(key));
                             end
                             hold off;
                         end
                     end
                 end
             end

             if ishandle(app.ANALOG_figure) 
                 if isvalid(app.ANALOG_figure)
                     if ~isempty(app.DataProvider)
                         if ~isempty(app.DataProvider.PMUdetails.ANALOG) && ~isempty(app.DataProvider.PMUdetails.SOC)
                             set(0,'CurrentFigure',app.ANALOG_figure);
                             [j,~] = size(app.DataProvider.PMUdetails.ANALOG);
                             l=1;
                             x=app.DataProvider.PMUdetails.SOC;
                             hold on;
                             for m=1:j
                                subplot(j,1,l);
                                y=app.DataProvider.PMUdetails.ANALOG(m,:);
                                plot(x,y,"Color",[0 0 0],"LineWidth",1);
                                l=l+1;
                                handle=app.DataProvider.ListPMU(app.DataProvider.DeviceID);
                                handle=handle.data.ANALOG(app.DataProvider.DeviceSTN);
                                k=keys(handle);
                                key=char(cell2mat(k(m)));
                                title("MAGNITUDE-"+string(app.DataProvider.DeviceSTN)+"-"+string(key));
                             end
                             hold off;
                         end
                     end
                 end
             end


             app.DataProvider.UpdatePlots();
             if isvalid(app.MapDialog)
                 app.MapDialog.UpdateMap();
             end
        end

        function t=InitTimer(app)
            t = timer;

            t.TimerFcn = @app.TimerCallback;

            t.Period = app.RefreshRate;

            t.TasksToExecute = Inf;
            t.ExecutionMode = 'fixedRate';
        end

        function LoadFiles(~)
            addpath("enums\");
            addpath("functions\");
            addpath("enums\command\");
            addpath("enums\frame\");
            addpath("enums\config\");
            addpath("enums\data\");
            addpath("models\");
            addpath("models\frame\");
            addpath("helpers\");
            addpath("dialogs\");
            addpath("repository\");
            addpath("controls\");
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadFiles();
            handle = ListOfPMU(app);
            app.DataProvider=handle;
            app.Timer=app.InitTimer();
            start(app.Timer);
        end

        % Menu selected function: MapaMenu
        function MapaMenuSelected(app, event)
            delete(app.MapDialog);
            if size(app.DataProvider.ListPMU)>0
                app.MapDialog = MapDialog(app);
            end   
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if  ishandle(app.Timer)
                if isvalid(app.Timer)
                    stop(app.Timer);
                end
            end
            delete(app.Timer);
            delete(app.OptionsDialog);
            delete(app.AddDialog);
            delete(app.DataProvider);
            delete(app.DeleteDialog);
            delete(app.PlotOptionsDialog);
            delete(app)
        end

        % Menu selected function: UstawieniaMenu
        function UstawieniaMenuSelected(app, event)
            delete(app.OptionsDialog);
            app.OptionsDialog = UstawieniaDialog(app);
        end

        % Menu selected function: DodajurzdzenieMenu
        function DodajurzdzenieMenuSelected(app, event)
            delete(app.AddDialog);
            if size(app.DataProvider.PolarPlotsMap)<=16
                app.AddDialog = DodajUrzadzenieDialog(app);
            end
        end

        % Menu selected function: UsuurzdzenieMenu
        function UsuurzdzenieMenuSelected(app, event)
            delete(app.DeleteDialog);
            if size(app.DataProvider.ListPMU)>0
                app.DeleteDialog = UsunUrzadzenieDialog(app);
            end
        end

        % Button pushed function: FREQ_BUTTON
        function FREQ_BUTTONButtonPushed(app, event)
            delete(app.FREQ_figure);
            app.FREQ_figure = figure("Name","FREQ");
        end

        % Button pushed function: DFREQ_BUTTON
        function DFREQ_BUTTONButtonPushed(app, event)
            delete(app.DFREQ_figure);
            app.DFREQ_figure = figure("Name","DFREQ");
        end

        % Button pushed function: MAGNITUDE_BUTTON
        function MAGNITUDE_BUTTONButtonPushed(app, event)
            delete(app.MAGNITUDE_figure);
            app.MAGNITUDE_figure = figure("Name","MAGNITUDE");
        end

        % Button pushed function: PHASE_BUTTON
        function PHASE_BUTTONButtonPushed(app, event)
            delete(app.PHASE_figure);
            app.PHASE_figure = figure("Name","PHASE");
        end

        % Button pushed function: ANALOG_BUTTON
        function ANALOG_BUTTONButtonPushed(app, event)
            delete(app.ANALOG_figure);
            app.ANALOG_figure = figure("Name","ANALOG");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AkcjeMenu = uimenu(app.UIFigure);
            app.AkcjeMenu.Text = 'Akcje';
            app.DodajurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.DodajurzdzenieMenu.MenuSelectedFcn = createCallbackFcn(app, @DodajurzdzenieMenuSelected, true);
            app.DodajurzdzenieMenu.Text = 'Dodaj urządzenie';
            app.UsuurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.UsuurzdzenieMenu.MenuSelectedFcn = createCallbackFcn(app, @UsuurzdzenieMenuSelected, true);
            app.UsuurzdzenieMenu.Text = 'Usuń urządzenie';
            app.MapaMenu = uimenu(app.UIFigure);
            app.MapaMenu.MenuSelectedFcn = createCallbackFcn(app, @MapaMenuSelected, true);
            app.MapaMenu.Text = 'Mapa';
            app.UstawieniaMenu = uimenu(app.UIFigure);
            app.UstawieniaMenu.MenuSelectedFcn = createCallbackFcn(app, @UstawieniaMenuSelected, true);
            app.UstawieniaMenu.Text = 'Ustawienia';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WykresykoloweTab = uitab(app.TabGroup);
            app.WykresykoloweTab.Title = 'Wykresy kołowe';
            app.GridForPlots = uigridlayout(app.WykresykoloweTab);
            app.WidokszczegowyTab = uitab(app.TabGroup);
            app.WidokszczegowyTab.Title = 'Widok szczegółowy';
            app.GridLayout2 = uigridlayout(app.WidokszczegowyTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.FREQLabel = uilabel(app.GridLayout3);
            app.FREQLabel.Text = 'FREQ';
            app.FREQ_BUTTON = uibutton(app.GridLayout3, 'push');
            app.FREQ_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @FREQ_BUTTONButtonPushed, true);
            app.FREQ_BUTTON.Text = 'Otwórz wykres';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.DFREQLabel = uilabel(app.GridLayout4);
            app.DFREQLabel.Text = 'DFREQ';
            app.DFREQ_BUTTON = uibutton(app.GridLayout4, 'push');
            app.DFREQ_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @DFREQ_BUTTONButtonPushed, true);
            app.DFREQ_BUTTON.Text = 'Otwórz wykres';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.MAGNITUDE_BUTTON = uibutton(app.GridLayout5, 'push');
            app.MAGNITUDE_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @MAGNITUDE_BUTTONButtonPushed, true);
            app.MAGNITUDE_BUTTON.Text = 'Otwórz wykres';
            app.MAGNITUDELabel = uilabel(app.GridLayout5);
            app.MAGNITUDELabel.Text = 'MAGNITUDE';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PHASE_BUTTON = uibutton(app.GridLayout6, 'push');
            app.PHASE_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @PHASE_BUTTONButtonPushed, true);
            app.PHASE_BUTTON.Text = 'Otwórz wykres';
            app.PHASELabel = uilabel(app.GridLayout6);
            app.PHASELabel.Text = 'PHASE';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.ANALOG_BUTTON = uibutton(app.GridLayout7, 'push');
            app.ANALOG_BUTTON.ButtonPushedFcn = createCallbackFcn(app, @ANALOG_BUTTONButtonPushed, true);
            app.ANALOG_BUTTON.Text = 'Otwórz wykres';
            app.ANALOGLabel = uilabel(app.GridLayout7);
            app.ANALOGLabel.Text = 'ANALOG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektSynchrofazory

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end