classdef CustomPolarPlot < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        PlotAndControls  matlab.ui.container.GridLayout
        GridForPlot      matlab.ui.container.GridLayout
        Title            matlab.ui.control.Label
        GridLayout2      matlab.ui.container.GridLayout
        SzczegyButton    matlab.ui.control.Button
        Options          matlab.ui.control.Button
        GridLayout5      matlab.ui.container.GridLayout
        SelectPlot       matlab.ui.control.StateButton
        GridLayout3      matlab.ui.container.GridLayout
        TimeQuality      matlab.ui.control.Lamp
        BdramkiLabel     matlab.ui.control.Label
    end

    properties (Access = private)
        MainWindowApp
        PolarAxes % Variable used to manage polar plot
    end
    
    properties (Access = public)
        ID
    end
    
    
    methods (Access = public)
        
        function UpdatePolarPlot(comp,Value,Angle)
            polarplot(comp.PolarAxes,[0 0],[0 0]);
            hold(comp.PolarAxes,"on");
            for i=1:size(Value')
            polarplot(comp.PolarAxes,[0 Angle(1,i)],[0 Value(1,i)]);
            end
            hold(comp.PolarAxes,"off");
        end
        
        function UpdateTimeQuality(comp,IsTimeOk)
            if IsTimeOk==true
                comp.TimeQuality.Enable="off";
            else
                 comp.TimeQuality.Enable="on";
            end
        end
        function SetHandle(comp,app)
            comp.MainWindowApp=app;
        end
        function SetTitle(comp,title)
            comp.Title.Text=title;
        end
        function RemoveSelection(comp)
            comp.SelectPlot.Value=false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            handle=polaraxes(comp.GridForPlot);
            handle.Layout.Row = 2;
            handle.Layout.Column = 1;
            comp.PolarAxes =handle;
            comp.TimeQuality.Enable="off";
        end

        % Button pushed function: Options
        function OptionsButtonPushed(comp, event)
            comp.MainWindowApp.PlotOptions(comp.ID);
        end

        % Value changed function: SelectPlot
        function SelectPlotValueChanged(comp, event)
            if comp.SelectPlot.Value==true
                comp.MainWindowApp.DeviceDetails(comp.ID);
            else
                comp.MainWindowApp.DeviceDetailsDelete();
            end
        end

        % Button pushed function: SzczegyButton
        function SzczegyButtonPushed(comp, event)
            comp.MainWindowApp.DeviceFrames(comp.ID);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.PlotAndControls = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.PlotAndControls);
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.BdramkiLabel = uilabel(comp.GridLayout3);
            comp.BdramkiLabel.Text = {'Błąd'; 'ramki'};
            comp.TimeQuality = uilamp(comp.GridLayout3);
            comp.GridLayout5 = uigridlayout(comp.GridLayout2);
            comp.SelectPlot = uibutton(comp.GridLayout5, 'state');
            comp.SelectPlot.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SelectPlotValueChanged, true);
            comp.SelectPlot.Text = 'Ustaw do widoku szczegółowego';
            comp.Options = uibutton(comp.GridLayout2, 'push');
            comp.Options.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @OptionsButtonPushed, true);
            comp.Options.Text = 'Opcje';
            comp.SzczegyButton = uibutton(comp.GridLayout2, 'push');
            comp.SzczegyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SzczegyButtonPushed, true);
            comp.SzczegyButton.Text = 'Szczegóły';
            comp.GridForPlot = uigridlayout(comp.PlotAndControls);
            comp.Title = uilabel(comp.GridForPlot);
            comp.Title.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end