classdef DodajUrzadzenieDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        DodajButton         matlab.ui.control.Button
        GridLayout4         matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        IdEditField         matlab.ui.control.NumericEditField
        IdEditFieldLabel    matlab.ui.control.Label
        GridLayout3         matlab.ui.container.GridLayout
        PortEditField       matlab.ui.control.NumericEditField
        PortEditFieldLabel  matlab.ui.control.Label
        GridLayout2         matlab.ui.container.GridLayout
        IpEditField         matlab.ui.control.EditField
        IpEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp=mainApp;
        end

        % Button pushed function: DodajButton
        function DodajButtonPushed(app, event)
            IsIpValid=false;
            IsIdValid=false;
            IsPortValid=false;     
            [Num, N] = sscanf(app.IpEditField.Value, '%d.%d.%d.%d');
            if N==4 && (all(Num))>=0 && (all(Num))<256
                IsIpValid=true;
            else
                app.IpEditField.FontColor=[1.00,0.00,0.00];
            end

            if  ~isempty(app.IdEditField.Value) && ~isKey(app.MainApp.MapOfPlots,app.IdEditField.Value)
                IsIdValid=true;
            else
                app.IdEditField.FontColor=[1.00,0.00,0.00];
            end

            if ~isempty(app.PortEditField.Value)
                IsPortValid=true;
            else
                app.PortEditField.FontColor=[1.00,0.00,0.00];
            end

            if IsIpValid==true && IsIdValid==true && IsPortValid==true
                app.MainApp.AddNewPMU(app.IpEditField.Value,uint16(app.IdEditField.Value),uint16(app.PortEditField.Value));
                delete(app);
            end
        end

        % Value changed function: IpEditField
        function IpEditFieldValueChanged(app, event)
            value = app.IpEditField.Value;
            app.IpEditField.FontColor=[0.00,0.00,0.00];
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            app.PortEditField.FontColor=[0.00,0.00,0.00];
        end

        % Value changed function: IdEditField
        function IdEditFieldValueChanged(app, event)
            value = app.IdEditField.Value;
            app.IdEditField.FontColor=[0.00,0.00,0.00];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IpEditFieldLabel = uilabel(app.GridLayout2);
            app.IpEditFieldLabel.Text = 'Ip';
            app.IpEditField = uieditfield(app.GridLayout2, 'text');
            app.IpEditField.ValueChangedFcn = createCallbackFcn(app, @IpEditFieldValueChanged, true);
            app.IpEditField.Placeholder = '127.0.0.1';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PortEditFieldLabel = uilabel(app.GridLayout3);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PortEditField.Limits = [1 65535];
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 4713;
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.IdEditFieldLabel = uilabel(app.GridLayout5);
            app.IdEditFieldLabel.Text = 'Id';
            app.IdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.IdEditField.Limits = [1 65534];
            app.IdEditField.ValueDisplayFormat = '%.0f';
            app.IdEditField.ValueChangedFcn = createCallbackFcn(app, @IdEditFieldValueChanged, true);
            app.IdEditField.Value = 1;
            app.DodajButton = uibutton(app.GridLayout, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @DodajButtonPushed, true);
            app.DodajButton.Text = {'Dodaj'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DodajUrzadzenieDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end