classdef PolarPlotOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        SetButton                 matlab.ui.control.Button
        WybierzIDPanel            matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        SelectOtherPlot           matlab.ui.control.DropDown
        WybierzIdurzdzeniaDropDownLabel  matlab.ui.control.Label
        CustomFreqPanel           matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        CustomFreqEdit            matlab.ui.control.NumericEditField
        WybierzczstotliwoEditFieldLabel  matlab.ui.control.Label
        OdniesieniedoButtonGroup  matlab.ui.container.ButtonGroup
        None                      matlab.ui.control.RadioButton
        OtherPlotFreq             matlab.ui.control.RadioButton
        CustomFreq                matlab.ui.control.RadioButton
        Default                   matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        Freq % Description
        Id
        Mode ReferenceType=ReferenceType.None;
        mainApp
    end
    
    methods (Access = public)
        
        function ClearEnabled(app)
            app.WybierzIDPanel.Enable="off";
            app.CustomFreqPanel.Enable="off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, ID)
            app.mainApp=mainApp;
            app.Id=ID;
            handle=mainApp.MapOfPlots(ID);
            app.Mode=handle.Mode;

            str=(keys(mainApp.MapOfPlots));
            items="";
            for i=1:size(str)
                if i==1
                    items=""+str(i);
                else
                    items=items+","+str(i);
                end
                
            end
            
            app.SelectOtherPlot.Items=items;


            switch app.Mode
                case ReferenceType.None
                    app.OdniesieniedoButtonGroup.SelectedObject=app.None;
                    app.OdniesieniedoButtonGroupSelectionChanged();
                case ReferenceType.Default
                    app.OdniesieniedoButtonGroup.SelectedObject=app.Default;
                    app.OdniesieniedoButtonGroupSelectionChanged();
                case ReferenceType.CustomFreq
                    app.OdniesieniedoButtonGroup.SelectedObject=app.CustomFreq;
                    if ~isempty(handle.Freq)
                        app.CustomFreqEdit.Value=handle.Freq;
                    end
                    app.OdniesieniedoButtonGroupSelectionChanged();
                case ReferenceType.OtherPMU
                    app.OdniesieniedoButtonGroup.SelectedObject=app.OtherPlotFreq;
                    if iskey(mainApp.MapOfPlots,handle.ID)
                        app.SelectOtherPlot.Value=string(handle.ID);
                    end
                    app.OdniesieniedoButtonGroupSelectionChanged();
            end

        end

        % Selection changed function: OdniesieniedoButtonGroup
        function OdniesieniedoButtonGroupSelectionChanged(app, event)
            selectedButton = app.OdniesieniedoButtonGroup.SelectedObject;
            app.ClearEnabled();
            if strcmp(selectedButton.Text,app.None.Text)
                app.Mode="None";
            elseif strcmp(selectedButton.Text,app.Default.Text)
                app.Mode="Default";
            elseif strcmp(selectedButton.Text,app.OtherPlotFreq.Text)
                app.WybierzIDPanel.Enable="on";
                app.Mode="OtherPMU";
            elseif strcmp(selectedButton.Text,app.CustomFreq.Text)
                app.CustomFreqPanel.Enable="on";
                app.Mode="CustomFreq";
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            handle=app.mainApp.MapOfPlots(app.Id);
            handle.Mode=app.Mode;
            handle.ID=str2double(app.SelectOtherPlot.Value);
            handle.Freq=app.CustomFreqEdit.Value;
            app.mainApp.PlotPlotOptionSelected();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OdniesieniedoButtonGroup = uibuttongroup(app.GridLayout);
            app.OdniesieniedoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OdniesieniedoButtonGroupSelectionChanged, true);
            app.OdniesieniedoButtonGroup.Title = 'Odniesienie do';
            app.Default = uiradiobutton(app.OdniesieniedoButtonGroup);
            app.Default.Text = '50Hz';
            app.CustomFreq = uiradiobutton(app.OdniesieniedoButtonGroup);
            app.CustomFreq.Text = 'Wybierz częstotliwość';
            app.OtherPlotFreq = uiradiobutton(app.OdniesieniedoButtonGroup);
            app.OtherPlotFreq.Text = 'Według innego wykresu';
            app.None = uiradiobutton(app.OdniesieniedoButtonGroup);
            app.None.Text = 'Brak';
            app.None.Value = true;
            app.CustomFreqPanel = uipanel(app.GridLayout);
            app.CustomFreqPanel.Enable = 'off';
            app.CustomFreqPanel.Title = 'Dowolna częstotliwość';
            app.GridLayout2 = uigridlayout(app.CustomFreqPanel);
            app.WybierzczstotliwoEditFieldLabel = uilabel(app.GridLayout2);
            app.WybierzczstotliwoEditFieldLabel.Text = 'Wybierz częstotliwość';
            app.CustomFreqEdit = uieditfield(app.GridLayout2, 'numeric');
            app.CustomFreqEdit.ValueDisplayFormat = '%.0f';
            app.WybierzIDPanel = uipanel(app.GridLayout);
            app.WybierzIDPanel.Enable = 'off';
            app.WybierzIDPanel.Title = 'Wybierz ID';
            app.GridLayout3 = uigridlayout(app.WybierzIDPanel);
            app.WybierzIdurzdzeniaDropDownLabel = uilabel(app.GridLayout3);
            app.WybierzIdurzdzeniaDropDownLabel.Text = 'Wybierz Id urządzenia';
            app.SelectOtherPlot = uidropdown(app.GridLayout3);
            app.SelectOtherPlot.Items = {};
            app.SelectOtherPlot.Value = {};
            app.SetButton = uibutton(app.GridLayout, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Ustaw odniesienie';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarPlotOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end